classdef DFK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApproximationsPanel             matlab.ui.container.Panel
        SmcalculationButtonGroup        matlab.ui.container.ButtonGroup
        HilberttransformButton          matlab.ui.control.RadioButton
        PrincipalValueButton            matlab.ui.control.RadioButton
        JButtonGroup                    matlab.ui.container.ButtonGroup
        AdiabaticButton                 matlab.ui.control.RadioButton
        Chi3Button                      matlab.ui.control.RadioButton
        TaylorEditField                 matlab.ui.control.NumericEditField
        TaylorEditFieldLabel            matlab.ui.control.Label
        smallth1andsmallth2Button       matlab.ui.control.RadioButton
        smallth2Button                  matlab.ui.control.RadioButton
        exactButton                     matlab.ui.control.RadioButton
        NlinesEditField                 matlab.ui.control.NumericEditField
        NlinesEditFieldLabel            matlab.ui.control.Label
        ExportfiguresButton             matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        contourmapButton                matlab.ui.control.RadioButton
        colormapButton                  matlab.ui.control.RadioButton
        CmCheckBox                      matlab.ui.control.CheckBox
        SmCheckBox                      matlab.ui.control.CheckBox
        higherordersCheckBox            matlab.ui.control.CheckBox
        m0CheckBox                      matlab.ui.control.CheckBox
        m1CheckBox_2                    matlab.ui.control.CheckBox
        m1CheckBox                      matlab.ui.control.CheckBox
        mSpinner                        matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        timeelapsedminEditField         matlab.ui.control.NumericEditField
        timeelapsedminEditFieldLabel    matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        basepathEditField               matlab.ui.control.EditField
        basepathEditFieldLabel          matlab.ui.control.Label
        SelectbasepathButton            matlab.ui.control.Button
        LookuptablePanel                matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        CreatelookuptableButton         matlab.ui.control.Button
        AkproductrangePanel             matlab.ui.container.Panel
        dth1EditField                   matlab.ui.control.NumericEditField
        dth1EditFieldLabel              matlab.ui.control.Label
        th1maxEditField                 matlab.ui.control.NumericEditField
        th1maxEditFieldLabel            matlab.ui.control.Label
        th2maxEditField                 matlab.ui.control.NumericEditField
        th2maxEditFieldLabel            matlab.ui.control.Label
        dth2EditField                   matlab.ui.control.NumericEditField
        dth2EditFieldLabel              matlab.ui.control.Label
        th2minEditField                 matlab.ui.control.NumericEditField
        th2minEditFieldLabel            matlab.ui.control.Label
        CalculatefromlookuptablefastButton  matlab.ui.control.Button
        ImpliedparametersPanel          matlab.ui.container.Panel
        IntegrationparametersPanel      matlab.ui.container.Panel
        th1maxEditField_2               matlab.ui.control.NumericEditField
        th1maxEditField_2Label          matlab.ui.control.Label
        th2EditField                    matlab.ui.control.NumericEditField
        th2EditFieldLabel               matlab.ui.control.Label
        EmaxeVEditField                 matlab.ui.control.NumericEditField
        EmaxeVEditFieldLabel            matlab.ui.control.Label
        lmaxEditField_2                 matlab.ui.control.NumericEditField
        lmaxEditField_2Label            matlab.ui.control.Label
        dth1minEditField                matlab.ui.control.NumericEditField
        dth1minEditFieldLabel           matlab.ui.control.Label
        LasermatterparametersPanel      matlab.ui.container.Panel
        EElectroopticeVEditField        matlab.ui.control.NumericEditField
        EElectroopticeVEditFieldLabel   matlab.ui.control.Label
        ElectricfieldVAEditField        matlab.ui.control.NumericEditField
        ElectricfieldVAEditFieldLabel   matlab.ui.control.Label
        gKeldyshEditField               matlab.ui.control.NumericEditField
        gKeldyshEditFieldLabel          matlab.ui.control.Label
        gadiabaticEditField             matlab.ui.control.NumericEditField
        gadiabaticEditFieldLabel        matlab.ui.control.Label
        UponderomotiveeVEditField       matlab.ui.control.NumericEditField
        UponderomotiveeVEditFieldLabel  matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        NumericalparametersPanel        matlab.ui.container.Panel
        dEintEditField                  matlab.ui.control.NumericEditField
        dEintEditFieldLabel             matlab.ui.control.Label
        maxBesselorderEditField         matlab.ui.control.NumericEditField
        maxBesselorderEditFieldLabel    matlab.ui.control.Label
        EmaxinteVEditField              matlab.ui.control.NumericEditField
        EmaxinteVEditFieldLabel         matlab.ui.control.Label
        lmaxEditField                   matlab.ui.control.NumericEditField
        lmaxEditFieldLabel              matlab.ui.control.Label
        mmaxEditField                   matlab.ui.control.NumericEditField
        mmaxEditFieldLabel              matlab.ui.control.Label
        ScanparametersPanel             matlab.ui.container.Panel
        DelaystepfsEditField            matlab.ui.control.NumericEditField
        DelaystepfsEditFieldLabel       matlab.ui.control.Label
        DelayrangefsEditField           matlab.ui.control.NumericEditField
        DelayrangefsEditFieldLabel      matlab.ui.control.Label
        XUVdeltaeVEditField             matlab.ui.control.NumericEditField
        XUVdeltaeVEditFieldLabel        matlab.ui.control.Label
        XUVhigheVEditField              matlab.ui.control.NumericEditField
        XUVhigheVEditFieldLabel         matlab.ui.control.Label
        XUVloweVEditField               matlab.ui.control.NumericEditField
        XUVloweVEditFieldLabel          matlab.ui.control.Label
        LaserparametersPanel            matlab.ui.container.Panel
        FrequencyradfsEditField         matlab.ui.control.NumericEditField
        FrequencyradfsEditFieldLabel    matlab.ui.control.Label
        IntensityTWcm2EditField         matlab.ui.control.NumericEditField
        IntensityTWcm2Label             matlab.ui.control.Label
        SolidparametersPanel            matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        Reducedmassm_eEditField         matlab.ui.control.NumericEditField
        Reducedmassm_eEditFieldLabel    matlab.ui.control.Label
        DipolemelatuEditField           matlab.ui.control.NumericEditField
        DipolemelatuEditFieldLabel      matlab.ui.control.Label
        ElectroneffmassEditField        matlab.ui.control.NumericEditField
        ElectroneffmassEditFieldLabel   matlab.ui.control.Label
        HoleeffmassEditField            matlab.ui.control.NumericEditField
        HoleeffmassEditFieldLabel       matlab.ui.control.Label
        BandgapeVEditField              matlab.ui.control.NumericEditField
        BandgapeVEditFieldLabel         matlab.ui.control.Label
        RefractieindexEditField         matlab.ui.control.NumericEditField
        RefractieindexEditFieldLabel    matlab.ui.control.Label
        FilmthicknessnmEditField        matlab.ui.control.NumericEditField
        FilmthicknessnmEditFieldLabel   matlab.ui.control.Label
        colorplotDropDown               matlab.ui.control.DropDown
        SavespectrogramButton           matlab.ui.control.Button
        LoadspectrogramButton           matlab.ui.control.Button
        CalculatepreciselyslowButton    matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        selected;
        cplot_not_empty = 0;
        ac_content;
        dc_content;
        av_content;
        ac_profile;
        dc_profile;
        analytical_nodes;
        Cm;
        Sm;
        warnings;
        obs = struct('d_OD',0,'d_Im_e',0,'d_Re_s',0,'d_alpha',0,'d_JDOS',0); % time-resolved DFKE observables
        obs_av = struct('d_OD',0,'d_Im_e',0,'d_Re_s',0,'d_alpha',0,'d_JDOS',0); % cycle-averaged DFKE observables
        obs_ac = struct('d_OD',0,'d_Im_e',0,'d_Re_s',0,'d_alpha',0,'d_JDOS',0); % adiabatic "ac" FKE observables
        obs_dc = struct('d_OD',0,'d_Im_e',0,'d_Re_s',0,'d_alpha',0,'d_JDOS',0); % static "dc" FKE observables
        use_lookup = false; % Description
        lookup_ok = false; % Description
        full_path; 
        base_path;
        DFK_matrix;
        d_OD;
        d_Im;
        d_Re_s;
        d_alpha;
        colorplot_content;
        content_units;
        delay;
        XUV;
        amp_c = 0.01;
        color_scale;
        scale_factor;
        cm;
        m; % Order of m to display Fourier Amplitudes
        sp;
        sc;
        np;
        lp;
        lm;
    end
    
    methods (Access = private)
        
        function update(app)
            app.scale_factor = app.xEditField.Value;
            app.color_scale = app.Slider.Value;
            app.amp_c = app.color_scale*app.scale_factor;     
            app.sp.BG = app.BandgapeVEditField.Value;
            app.sp.z_film = 10*app.FilmthicknessnmEditField.Value; % nm to A
            app.sp.n = app.RefractieindexEditField.Value;
            app.sp.eEffMass = app.ElectroneffmassEditField.Value;
            app.sp.hEffMass = app.HoleeffmassEditField.Value;
            app.sp.p_matr_au = app.DipolemelatuEditField.Value;
            app.sp.n_XUV = 1; % can be changed if the value of n is available
            app.sp.mtx = app.DropDown.Value; % Manual entry or Kane model
            app.sc.XUVlow = app.XUVloweVEditField.Value;
            app.sc.XUVhigh = app.XUVhigheVEditField.Value;
            app.sc.XUVdelta = app.XUVdeltaeVEditField.Value;
            app.sc.delayRange = app.DelayrangefsEditField.Value;
            app.sc.delayDelta = app.DelaystepfsEditField.Value;
            app.np.m_max = app.mmaxEditField.Value;
            app.np.l_max = app.lmaxEditField.Value;
            app.np.maxl_int = app.np.l_max - 2*app.np.m_max; % 40 %2020 comment: since index runs over l-2m, this index must be lower than pre-calculated one
            app.np.th2_min = app.th2minEditField.Value;
            app.np.dth2 = app.dth2EditField.Value;
            app.np.th2_max = app.th2maxEditField.Value;
            app.np.Emax_int = app.EmaxinteVEditField.Value;
            app.np.dEint = app.dEintEditField.Value;
            if string(app.JButtonGroup.SelectedObject.Text)=='exact'
                app.np.approx.type = 'exact';
            elseif string(app.JButtonGroup.SelectedObject.Text)==app.smallth2Button.Text
                app.np.approx.type = 'small th2';
            elseif string(app.JButtonGroup.SelectedObject.Text)==app.smallth1andsmallth2Button.Text
                app.np.approx.type = 'Taylor'
            elseif string(app.JButtonGroup.SelectedObject.Text)==app.Chi3Button.Text
                app.np.approx.type = 'Chi3'
            elseif string(app.JButtonGroup.SelectedObject.Text)==app.AdiabaticButton.Text
                app.np.approx.type = 'Adiabatic'
            end  
            
            if string(app.SmcalculationButtonGroup.SelectedObject.Text)=='Principal Value'
                app.np.Hilbert = 0;
            elseif string(app.SmcalculationButtonGroup.SelectedObject.Text)=='Hilbert transform'
                app.np.Hilbert = 1;
            end  
                        
            app.np.approx.order = app.TaylorEditField.Value;
            %app.np.ponderomotive = 0; optiaonal switching off of Up - not
            %implemented
            app.np.maxBessel = app.maxBesselorderEditField.Value;
            app.lp.vac_intensity = app.IntensityTWcm2EditField.Value;
            app.lp.w = app.FrequencyradfsEditField.Value;
            app.selected.cos = app.CmCheckBox.Value;
            app.selected.sin = app.SmCheckBox.Value;
            app.selected.m = ones(2*app.np.m_max+1,1);
            app.selected.m(app.np.m_max+1) = app.m0CheckBox.Value;
            app.selected.m(app.np.m_max) = app.m1CheckBox.Value;
            app.selected.m(app.np.m_max+2) = app.m1CheckBox_2.Value;
            app.selected.m(app.np.m_max+1) = app.m0CheckBox.Value;
            app.selected.m(1:app.np.m_max-1) = app.higherordersCheckBox.Value;
            app.selected.m(app.np.m_max+3:end) = app.higherordersCheckBox.Value;
            
            [app.lp,app.sp,app.sc,app.np,app.lm]=setup_DFK_scan(app.lp,app.sp,app.sc,app.np);
            
            app.Reducedmassm_eEditField.Value = app.sp.mu/cnst.e_mass;
            app.UponderomotiveeVEditField.Value = app.lm.U_pond;
            app.ElectricfieldVAEditField.Value = app.lm.F;
            app.gadiabaticEditField.Value = app.lm.gamma_FKE;
            app.gKeldyshEditField.Value = app.lm.gamma_Keldysh;          
            app.th1maxEditField_2.Value = app.np.th1_max;
            app.dth1minEditField.Value = app.np.dth1;
            app.lmaxEditField_2.Value = app.np.maxl_int;
            app.EmaxeVEditField.Value = app.np.Emax;
            app.th2EditField.Value = app.lm.th2;
            
            [app.obs_ac,app.obs_dc] = static_FK_module(app.sc,app.lm,app.lp,app.sp);           
            
            % Approximation for the oscillation maxima
            num_nodes = 5;
            app.analytical_nodes = FK_nodes(app.sp.BG,app.sp.mu,app.lm.F,num_nodes);
            
            app.EElectroopticeVEditField.Value = e_electrooptic(app.sp.mu,app.lm.F);
            
            app.base_path = app.basepathEditField.Value;
            [app.lookup_ok,app.warnings,app.full_path] = search_lookup_directories(app.base_path,app.lm.th2,app.np);
            if app.lookup_ok
                app.EditField.Value = 'Lookup available';
            else
                app.EditField.Value = 'Lookup not available';
            end
            
            update_observable_type(app);
            update_av_profiles(app);
        end
        
        
        function update_colorplot(app)
            if string(app.ButtonGroup.SelectedObject.Text)=='contour map'
                contour(app.UIAxes,app.sc.delay,app.sc.XUV,app.colorplot_content,app.NlinesEditField.Value);
            else
                pcolor(app.UIAxes,app.sc.delay,app.sc.XUV,app.colorplot_content);
            end
            hold(app.UIAxes,"on");
            plot(app.UIAxes,[app.sc.delay(1) app.sc.delay(end)],[app.sp.BG app.sp.BG],'Color','black','LineStyle',"-");
            plot(app.UIAxes,[app.sc.delay(1) app.sc.delay(end)],[app.sp.BG + app.lm.U_pond  app.sp.BG + app.lm.U_pond ],'Color','black','LineStyle',"--");
            hold(app.UIAxes,"off");
            xlim(app.UIAxes,[-app.DelayrangefsEditField.Value/2 app.DelayrangefsEditField.Value/2]);
            ylim(app.UIAxes,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
            cb = colorbar(app.UIAxes,"eastoutside");
            colormap(app.UIAxes,app.cm);
            caxis(app.UIAxes,[-app.amp_c app.amp_c]);
            shading(app.UIAxes,"interp");
            set(app.UIAxes,'Box','on');
            set(app.UIAxes,'layer','top');
            title(app.UIAxes,app.content_units);   
        end
        
        function update_observable_type(app)
            value = app.colorplotDropDown.Value;
            switch value
                case 'OD'
                    app.ac_content = app.obs_ac.d_OD;
                    app.dc_content = app.obs_dc.d_OD;
                    app.av_content = app.obs_av.d_OD;
                    app.colorplot_content = app.obs.d_OD;
                    app.content_units = '\Delta OD';
                case 'Re(sigma)'
                    app.ac_content = app.obs_ac.d_Re_s;
                    app.dc_content = app.obs_dc.d_Re_s;
                    app.av_content = app.obs_av.d_Re_s;
                    app.colorplot_content = app.obs.d_Re_s;
                    app.content_units = '\Delta Re(\sigma) (S/m)';
                case 'Im(e)'
                    app.ac_content = app.obs_ac.d_Im_e;
                    app.dc_content = app.obs_dc.d_Im_e;
                    app.av_content = app.obs_av.d_Im_e;
                    app.colorplot_content = app.obs.d_Im_e;
                    app.content_units = '\Delta Im(\epsilon)';
                case 'Absorption'
                    % this must be probably 1e8 because 1/A = 10^10 1/m =
                    % 10^8 /cm
                    app.ac_content = app.obs_ac.d_alpha*1e7;
                    app.dc_content = app.obs_dc.d_alpha*1e7;
                    app.av_content = app.obs_av.d_alpha*1e7;
                    app.colorplot_content = app.obs.d_alpha*1e7;
                    app.content_units = '\Delta \alpha (1/cm)';
                case 'JDOS'
                    app.ac_content = app.obs_ac.d_JDOS;
                    app.dc_content = app.obs_dc.d_JDOS;
                    app.av_content = app.obs_av.d_JDOS;
                    app.colorplot_content = app.obs.d_JDOS;
                    app.content_units = '\Delta JDOS (State/eV/A^3)';
                otherwise
            end
            
        end
        
        function create_lookup(app)
            update(app);
            % Overwrite with desired lookup table range and finess of theta1
            app.np.th1_max = app.th1maxEditField.Value;
            app.np.dth1 = app.dth1EditField.Value;
            app.np.th1 = linspace(0,app.np.th1_max,app.np.th1_max/app.np.dth1);
            
            app.use_lookup = true;
            [new_path,dirname,app.np] = calculation_type(app.base_path,app.np,app.lm,app.use_lookup);
            app.lookup_ok = false;
            t_start = tic();
            generate_tables = true;
            calculate_CmSm = false;
            DFK_generate(app.sc,app.lm,app.lp,app.sp,app.np,new_path,generate_tables,calculate_CmSm); 
            app.timeelapsedminEditField.Value = toc(t_start)/60;
            update(app);
        end
        function update_av_profiles(app)         
            xscale = max(abs(app.dc_content));
            f0 = plot(app.UIAxes3,[-1.1*xscale 1.1*xscale],[app.sp.BG app.sp.BG],'Color','black');
            hold(app.UIAxes3,"on");
            f5 = plot(app.UIAxes3,[-1.1*xscale 1.1*xscale],[app.sp.BG + app.lm.U_pond app.sp.BG + app.lm.U_pond],'Color','black','LineStyle',"--");
            f1 = plot(app.UIAxes3,app.ac_content,app.sc.XUV,'-c');
            f2 = plot(app.UIAxes3,app.dc_content,app.sc.XUV,':b');
            f3 = scatter(app.UIAxes3,zeros(1,length(app.analytical_nodes)),app.analytical_nodes,'filled','ok');
            if any(app.av_content) && (size(app.av_content,2) == length(app.sc.XUV))
               f4 = plot(app.UIAxes3,app.av_content,app.sc.XUV,'-m');
               legend(app.UIAxes3,[f0 f1 f2 f3 f4],{'band gap','adiabatic','DC','DC nodes','DFK mean'});
            else
               legend(app.UIAxes3,[f0 f1 f2 f3],{'band gap','adiabatic','DC','DC nodes'});
            end
     
            xlim(app.UIAxes3,[-1.1*xscale 1.1*xscale]);
            ylim(app.UIAxes3,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
            xlabel(app.UIAxes3,app.content_units);
            hold(app.UIAxes3,"off");
        end
        
        function update_FIG2(app)
            sz = size(app.Cm,1);
            indx = (sz-1)/2+app.m+1;
            if (indx>0)*(indx<=sz)          
                plot(app.UIAxes4,app.Cm(indx,:),app.sc.XUV,'r');
                hold(app.UIAxes4,"on");
                plot(app.UIAxes4,app.Sm(indx,:),app.sc.XUV,'b');
                ylim(app.UIAxes4,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
                hold(app.UIAxes4,"off");
                legend(app.UIAxes4,'cos','sin');
            else
                empty_array = zeros(1,length(app.sc.XUV));
                plot(app.UIAxes4,empty_array,app.sc.XUV,'r');
                hold(app.UIAxes4,"on");
                plot(app.UIAxes4,empty_array,app.sc.XUV,'b');
                ylim(app.UIAxes4,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
                hold(app.UIAxes4,"off");
                legend(app.UIAxes4,'cos','sin');
            end
        end
        
        function update_calculations(app)
            app.obs = make_spectrogram(app.lp,app.sp,app.sc,app.np,app.Cm,app.Sm,app.selected);
            app.cplot_not_empty = 1;
            app.obs_av = time_averaged_DFK(app.lp,app.sp,app.sc,app.np,app.Cm,app.Sm);
            
            update_observable_type(app);
            update_colorplot(app);       
            update_FIG2(app);
            update_av_profiles(app); 
        end
        
        function export_figures(app)
            % Colorplot 
            if app.cplot_not_empty
                fig1 = figure; ax1 = gca;
                if string(app.ButtonGroup.SelectedObject.Text)=='contour map'
                    contour(ax1,app.sc.delay,app.sc.XUV,app.colorplot_content,app.NlinesEditField.Value);
                else
                    pcolor(ax1,app.sc.delay,app.sc.XUV,app.colorplot_content);
                end
                hold(ax1,"on");
                plot(ax1,[app.sc.delay(1) app.sc.delay(end)],[app.sp.BG app.sp.BG],'Color','black','LineStyle',"-");
                plot(ax1,[app.sc.delay(1) app.sc.delay(end)],[app.sp.BG + app.lm.U_pond  app.sp.BG + app.lm.U_pond ],'Color','black','LineStyle',"--");
                hold(ax1,"off");
                xlim(ax1,[-app.DelayrangefsEditField.Value/2 app.DelayrangefsEditField.Value/2]);
                ylim(ax1,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
                cb = colorbar(ax1,"eastoutside");
                colormap(ax1,app.cm);
                caxis(ax1,[-app.amp_c app.amp_c]);
                shading(ax1,"interp");
                set(ax1,'Box','on');
                set(ax1,'layer','top');
                title(ax1,app.content_units);  
                xlabel(ax1,'Delay (fs)');
                ylabel(ax1,'Photon energy (eV)');
            end
            
            %Fourier amplitudes
            fig2 = figure; ax2 = gca;
            sz = size(app.Cm,1);
            indx = (sz-1)/2+app.m+1;
            if (indx>0)*(indx<=sz)               
                plot(ax2,app.Cm(indx,:),app.sc.XUV,'r');
                hold(ax2,"on");
                plot(ax2,app.Sm(indx,:),app.sc.XUV,'b');
                ylim(ax2,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
                hold(ax2,"off");
                legend(ax2,'cos','sin');
            else
                empty_array = zeros(1,length(app.sc.XUV));
                plot(ax2,empty_array,app.sc.XUV,'r');
                hold(ax2,"on");
                plot(ax2,empty_array,app.sc.XUV,'b');
                ylim(ax2,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
                hold(ax2,"off");
                legend(ax2,'cos','sin');
            end
            xlabel(ax2,'Amplitude');
            ylabel(ax2,'Photon energy (eV)');
            
            %Average profiles
            fig3 = figure; ax3 = gca;
            xscale = max(abs(app.dc_content));
            f0 = plot(ax3,[-1.1*xscale 1.1*xscale],[app.sp.BG app.sp.BG],'Color','black');
            hold(ax3,"on");
            f5 = plot(ax3,[-1.1*xscale 1.1*xscale],[app.sp.BG + app.lm.U_pond app.sp.BG + app.lm.U_pond],'Color','black','LineStyle',"--");
            f1 = plot(ax3,app.ac_content,app.sc.XUV,'-c');
            f2 = plot(ax3,app.dc_content,app.sc.XUV,':b');
            f3 = scatter(ax3,zeros(1,length(app.analytical_nodes)),app.analytical_nodes,'filled','ok');
            if any(app.av_content) && (size(app.av_content,2) == length(app.sc.XUV))
              f4 = plot(ax3,app.av_content,app.sc.XUV,'-m');
              legend(ax3,[f0 f1 f2 f3 f4],{'band gap','adiabatic','DC','DC nodes','DFK mean'});
            else
              legend(ax3,[f0 f1 f2 f3],{'band gap','adiabatic','DC','DC nodes'});
            end
            xlim(ax3,[-1.1*xscale 1.1*xscale]);
            ylim(ax3,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
            xlabel(ax3,app.content_units);
            ylabel(ax3,'Photon energy (eV)');
            hold(ax3,"off");     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update(app);
            app.IntensityTWcm2Label.Text = ['Intensity TW/cm' char(178)];
            app.XUVdeltaeVEditFieldLabel.Text = [char(948) 'XUV'];
            app.gadiabaticEditFieldLabel.Text = [char(947) ' adiabatic'];
            app.gKeldyshEditFieldLabel.Text = [char(947) ' Keldysh'];
            app.th2minEditFieldLabel.Text = ['min ' char(952) char(8322)];
            app.dth2EditFieldLabel.Text = [char(948) char(952) char(8322)];
            app.th2maxEditFieldLabel.Text = ['max ' char(952) char(8322)];
            app.dth1EditFieldLabel.Text = [char(948) char(952) char(8321)];
            app.th2EditFieldLabel.Text = [char(952) char(8322)];
            app.th1maxEditField_2Label.Text = ['max ' char(952) char(8321)];
            app.th1maxEditFieldLabel.Text = ['max ' char(952) char(8321)];
            app.dth1minEditFieldLabel.Text = ['min ' char(948) char(952) char(8321)];
            app.DipolemelatuEditFieldLabel.Text = [char(124) 'p' char(124) char(178) ' (at.u.)'];
            app.Reducedmassm_eEditFieldLabel.Text = [char(956) '/m' char(8320)];
            app.ElectroneffmassEditFieldLabel.Text = ['m' char(8337) '/m' char(8320)];
            app.HoleeffmassEditFieldLabel.Text = ['m' char(8341) '/m' char(8320)];
            app.UponderomotiveeVEditFieldLabel.Text = ['U' char(8346) ' (eV)'];
            app.ElectricfieldVAEditFieldLabel.Text = ['Electric field (V/' char(8491) ')'];
            app.dEintEditFieldLabel.Text = [char(948) 'E (eV)'];
            app.basepathEditField.Value = pwd;
        
            app.smallth2Button.Text = [char(952) char(8322) ' < 1'];
            app.smallth1andsmallth2Button.Text = [char(952) char(8321) ',' char(952) char(8322) ' < 1'];
            app.JButtonGroup.Title = ['J(' char(952) char(8321) ',' char(952) char(8322) ')'];
            app.Chi3Button.Text = [char(967) '(3)']
            colorbar(app.UIAxes,"eastoutside");
            
            xlim(app.UIAxes,[-app.DelayrangefsEditField.Value/2 app.DelayrangefsEditField.Value/2]);
            ylim(app.UIAxes,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
            ylim(app.UIAxes4,[app.XUVloweVEditField.Value app.XUVhigheVEditField.Value]);
            cm_struct = load('redblue.mat');
            app.cm = cm_struct.redblue;
            cb = colorbar(app.UIAxes,"eastoutside");
            colormap(app.UIAxes,app.cm);
        end

        % Button pushed function: CalculatepreciselyslowButton
        function CalculatepreciselyslowButtonPushed(app, event)
            t_start = tic();
            update(app);
            app.use_lookup = false;
            [new_path,dirname,app.np] = calculation_type(app.base_path,app.np,app.lm,app.use_lookup);
            
            %% Main: generate lookup table or exact value
            generate_tables = true;
            calculate_CmSm = true;
            DFK_generate(app.sc,app.lm,app.lp,app.sp,app.np,new_path,generate_tables,calculate_CmSm); 
            [app.Cm,app.Sm]= Cm_Sm_load(new_path);
            app.timeelapsedminEditField.Value = toc(t_start)/60;
            update_calculations(app);
        end

        % Value changed function: BandgapeVEditField
        function BandgapeVEditFieldValueChanged(app, event)
            update(app);
        end

        % Size changed function: SolidparametersPanel
        function SolidparametersPanelSizeChanged(app, event)
            update(app);
        end

        % Value changed function: FilmthicknessnmEditField
        function FilmthicknessnmEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: RefractieindexEditField
        function RefractieindexEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: ElectroneffmassEditField
        function ElectroneffmassEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: HoleeffmassEditField
        function HoleeffmassEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: XUVloweVEditField
        function XUVloweVEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: XUVhigheVEditField
        function XUVhigheVEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: XUVdeltaeVEditField
        function XUVdeltaeVEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: DelayrangefsEditField
        function DelayrangefsEditFieldValueChanged(app, event)
            update(app);
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: DelaystepfsEditField
        function DelaystepfsEditFieldValueChanged(app, event)
            update(app);
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: mmaxEditField
        function mmaxEditFieldValueChanged(app, event)
            update(app);
        end

        % Callback function
        function lmaxEditFieldValueChanged(app, event)
            update(app);
        end

        % Callback function
        function dtheta2EditFieldValueChanged(app, event)
            update(app);
        end

        % Callback function
        function EmaxinteVEditFieldValueChanged(app, event)
            update(app);
        end

        % Callback function
        function dEintEditFieldValueChanged(app, event)
            update(app);
        end

        % Callback function
        function th2minEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: IntensityTWcm2EditField
        function IntensityTWcm2EditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: FrequencyradfsEditField
        function FrequencyradfsEditFieldValueChanged(app, event)
            update(app);
        end

        % Value changed function: DipolemelatuEditField
        function DipolemelatuEditFieldValueChanged(app, event)
            update(app);
        end

        % Button pushed function: SavespectrogramButton
        function SavespectrogramButtonPushed(app, event)
            update(app);
            parameters = {app.sp,app.sc,app.lp,app.np,app.lm};
            spectrogram = {app.obs,app.obs_av,app.Cm,app.Sm};
            data = {parameters,spectrogram};
            [filename, pathname] = uiputfile('*.mat');
            save([pathname,filename],'data','-mat');
        end

        % Button pushed function: LoadspectrogramButton
        function LoadspectrogramButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat');
            p = load([pathname,filename]);
            app.sp = p.data{1}{1};
            app.sc = p.data{1}{2};
            app.lp = p.data{1}{3};
            app.np = p.data{1}{4};
            app.lm = p.data{1}{5};
            
            app.obs = p.data{2}{1};
            app.obs_av = p.data{2}{2};
            app.Cm = p.data{2}{3};
            app.Sm = p.data{2}{4};
            
            app.BandgapeVEditField.Value = app.sp.BG;
            app.FilmthicknessnmEditField.Value = app.sp.z_film/10;
            app.RefractieindexEditField.Value = app.sp.n;
            app.ElectroneffmassEditField.Value = app.sp.eEffMass;
            app.HoleeffmassEditField.Value = app.sp.hEffMass;
            app.DipolemelatuEditField.Value = app.sp.p_matr_au;
            %app.DropDown.Value = app.sp.mtx; enable on release
            app.XUVloweVEditField.Value = app.sc.XUVlow;
            app.XUVhigheVEditField.Value = app.sc.XUVhigh;
            app.XUVdeltaeVEditField.Value = app.sc.XUVdelta;
            app.DelayrangefsEditField.Value = app.sc.delayRange;
            app.DelaystepfsEditField.Value = app.sc.delayDelta;
            app.mmaxEditField.Value = app.np.m_max;
            app.lmaxEditField.Value = app.np.l_max;
            app.dth2EditField.Value = app.np.dth2;
            app.EmaxinteVEditField.Value = app.np.Emax_int;
            app.dEintEditField.Value = app.np.dEint;
            app.maxBesselorderEditField.Value = app.np.maxBessel;
            app.th2minEditField.Value = app.np.th2_min;
            app.IntensityTWcm2EditField.Value = app.lp.vac_intensity;
            app.FrequencyradfsEditField.Value = app.lp.w;
            
            app.cplot_not_empty = 1;
            
            update(app);
            update_observable_type(app);
            update_colorplot(app);     
            update_FIG2(app);               
            
        end

        % Value changed function: Reducedmassm_eEditField
        function Reducedmassm_eEditFieldValueChanged(app, event)
            mu_abs = app.Reducedmassm_eEditField.Value;
            app.ElectroneffmassEditField.Value = 2*mu_abs;
            app.HoleeffmassEditField.Value = 2*mu_abs;
            update(app);
        end

        % Value changed function: dth2EditField
        function dth2EditFieldValueChanged2(app, event)
            update(app);
        end

        % Value changed function: EmaxinteVEditField
        function EmaxinteVEditFieldValueChanged2(app, event)
            update(app);
        end

        % Value changed function: dEintEditField
        function dEintEditFieldValueChanged2(app, event)
            update(app);
        end

        % Value changed function: maxBesselorderEditField
        function maxBesselorderEditFieldValueChanged2(app, event)
            update(app);
        end

        % Value changed function: th2minEditField
        function th2minEditFieldValueChanged2(app, event)
            update(app);
        end

        % Value changed function: colorplotDropDown
        function colorplotDropDownValueChanged(app, event)
            update_observable_type(app);
            update_av_profiles(app);
            if app.cplot_not_empty
                update_colorplot(app);
            end
        end

        % Value changed function: th2maxEditField
        function th2maxEditFieldValueChanged(app, event)
            value = app.th2maxEditField.Value;
            update(app);
        end

        % Callback function
        function SelectlookupfolderButtonPushed(app, event)
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            switch app.IsolateharmonicButtonGroup.SelectedObject.Text
            end
        end

        % Button pushed function: CalculatefromlookuptablefastButton
        function CalculatefromlookuptablefastButtonPushed(app, event)
            if app.lookup_ok
            t_start = tic();
            update(app);
            app.use_lookup = true;
            [new_path,dirname,app.np] = calculation_type(app.base_path,app.np,app.lm,app.use_lookup);            
            %% Main: generate lookup table
            generate_tables = false;
            calculate_CmSm = true;
            DFK_generate(app.sc,app.lm,app.lp,app.sp,app.np,new_path,generate_tables,calculate_CmSm); 
            [app.Cm,app.Sm]= Cm_Sm_load(new_path);   
            app.timeelapsedminEditField.Value = toc(t_start)/60;
            update_calculations(app);
            end   
        end

        % Button pushed function: SelectbasepathButton
        function SelectbasepathButtonPushed(app, event)
            app.base_path = uigetdir();
            app.basepathEditField.Value = app.base_path;
            update(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
             app.scale_factor = app.xEditField.Value;
             app.amp_c = app.color_scale*app.scale_factor;
             update_colorplot(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.color_scale = app.Slider.Value;
            app.amp_c = app.color_scale*app.scale_factor;
            update_colorplot(app);
        end

        % Button pushed function: CreatelookuptableButton
        function CreatelookuptableButtonPushed(app, event)
            create_lookup(app);
        end

        % Value changing function: mSpinner
        function mSpinnerValueChanging(app, event)
            app.m = event.Value;
            update_FIG2(app);
        end

        % Value changed function: m0CheckBox
        function m0CheckBoxValueChanged(app, event)
            value = app.m0CheckBox.Value;
            app.selected.m(app.np.m_max+1) = value;
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: m1CheckBox
        function m1CheckBoxValueChanged(app, event)
            app.selected.m(app.np.m_max) = app.m1CheckBox.Value;
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: m1CheckBox_2
        function m1CheckBox_2ValueChanged(app, event)
            value = app.m1CheckBox_2.Value;
            app.selected.m(app.np.m_max+2) = value;
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: higherordersCheckBox
        function higherordersCheckBoxValueChanged(app, event)
            value = app.higherordersCheckBox.Value;
            app.selected.m(1:app.np.m_max-1) = value;
            app.selected.m(app.np.m_max+3:end) = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            update(app);
        end

        % Value changed function: SmCheckBox
        function SmCheckBoxValueChanged(app, event)
            app.selected.sin = app.SmCheckBox.Value;
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Value changed function: CmCheckBox
        function CmCheckBoxValueChanged(app, event)
            app.selected.cos = app.CmCheckBox.Value; 
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Callback function
        function ButtonGroup_2SelectionChanged(app, event)
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Button pushed function: ExportfiguresButton
        function ExportfiguresButtonPushed(app, event)
            export_figures(app);
        end

        % Value changed function: NlinesEditField
        function NlinesEditFieldValueChanged(app, event)
            if app.cplot_not_empty
               update_calculations(app);
            end
        end

        % Selection changed function: SmcalculationButtonGroup
        function SmcalculationButtonGroupSelectionChanged(app, event)
            %update_calculations(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectrogram ')
            xlabel(app.UIAxes, 'Delay (fs)')
            ylabel(app.UIAxes, 'Photon energy (eV)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Absorption profile')
            xlabel(app.UIAxes3, '\Delta OD')
            ylabel(app.UIAxes3, 'Photon energy (eV)')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Fourier amplitudes')
            xlabel(app.UIAxes4, 'Amplitude')
            ylabel(app.UIAxes4, 'Photon energy (eV)')
            app.CalculatepreciselyslowButton = uibutton(app.UIFigure, 'push');
            app.CalculatepreciselyslowButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepreciselyslowButtonPushed, true);
            app.CalculatepreciselyslowButton.Text = 'Calculate precisely (slow)';
            app.LoadspectrogramButton = uibutton(app.UIFigure, 'push');
            app.LoadspectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspectrogramButtonPushed, true);
            app.LoadspectrogramButton.Text = 'Load spectrogram';
            app.SavespectrogramButton = uibutton(app.UIFigure, 'push');
            app.SavespectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SavespectrogramButtonPushed, true);
            app.SavespectrogramButton.Text = 'Save spectrogram';
            app.colorplotDropDown = uidropdown(app.UIFigure);
            app.colorplotDropDown.Items = {'OD', 'Re(sigma)', 'Im(e)', 'Absorption', 'JDOS'};
            app.colorplotDropDown.ValueChangedFcn = createCallbackFcn(app, @colorplotDropDownValueChanged, true);
            app.colorplotDropDown.Value = 'OD';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.SolidparametersPanel = uipanel(app.InputPanel);
            app.SolidparametersPanel.Title = 'Solid parameters';
            app.SolidparametersPanel.SizeChangedFcn = createCallbackFcn(app, @SolidparametersPanelSizeChanged, true);
            app.FilmthicknessnmEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.FilmthicknessnmEditFieldLabel.Text = 'Film thickness (nm)';
            app.FilmthicknessnmEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.FilmthicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @FilmthicknessnmEditFieldValueChanged, true);
            app.FilmthicknessnmEditField.Value = 45;
            app.RefractieindexEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.RefractieindexEditFieldLabel.Text = 'Refractie index';
            app.RefractieindexEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.RefractieindexEditField.ValueChangedFcn = createCallbackFcn(app, @RefractieindexEditFieldValueChanged, true);
            app.RefractieindexEditField.Value = 1;
            app.BandgapeVEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.BandgapeVEditFieldLabel.Text = 'Band gap (eV)';
            app.BandgapeVEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.BandgapeVEditField.ValueChangedFcn = createCallbackFcn(app, @BandgapeVEditFieldValueChanged, true);
            app.BandgapeVEditField.Value = 29.5;
            app.HoleeffmassEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.HoleeffmassEditFieldLabel.Text = 'Hole eff mass';
            app.HoleeffmassEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.HoleeffmassEditField.ValueChangedFcn = createCallbackFcn(app, @HoleeffmassEditFieldValueChanged, true);
            app.HoleeffmassEditField.Value = 10000;
            app.ElectroneffmassEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.ElectroneffmassEditFieldLabel.Text = 'Electron eff mass';
            app.ElectroneffmassEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.ElectroneffmassEditField.ValueChangedFcn = createCallbackFcn(app, @ElectroneffmassEditFieldValueChanged, true);
            app.ElectroneffmassEditField.Value = 0.4;
            app.DipolemelatuEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.DipolemelatuEditFieldLabel.Text = 'Dipole m.el. (at.u.)';
            app.DipolemelatuEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.DipolemelatuEditField.ValueChangedFcn = createCallbackFcn(app, @DipolemelatuEditFieldValueChanged, true);
            app.DipolemelatuEditField.Value = 0.928;
            app.Reducedmassm_eEditFieldLabel = uilabel(app.SolidparametersPanel);
            app.Reducedmassm_eEditFieldLabel.Text = 'Reduced mass (m_e)';
            app.Reducedmassm_eEditField = uieditfield(app.SolidparametersPanel, 'numeric');
            app.Reducedmassm_eEditField.ValueChangedFcn = createCallbackFcn(app, @Reducedmassm_eEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.SolidparametersPanel);
            app.DropDown.Items = {'Kane', 'Manual'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Kane';
            app.LaserparametersPanel = uipanel(app.InputPanel);
            app.LaserparametersPanel.Title = 'Laser parameters';
            app.IntensityTWcm2Label = uilabel(app.LaserparametersPanel);
            app.IntensityTWcm2Label.Text = 'Intensity (TW/cm^2)';
            app.IntensityTWcm2EditField = uieditfield(app.LaserparametersPanel, 'numeric');
            app.IntensityTWcm2EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityTWcm2EditFieldValueChanged, true);
            app.IntensityTWcm2EditField.Value = 7;
            app.FrequencyradfsEditFieldLabel = uilabel(app.LaserparametersPanel);
            app.FrequencyradfsEditFieldLabel.Text = 'Frequency (rad/fs)';
            app.FrequencyradfsEditField = uieditfield(app.LaserparametersPanel, 'numeric');
            app.FrequencyradfsEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyradfsEditFieldValueChanged, true);
            app.FrequencyradfsEditField.Value = 2.4149;
            app.ScanparametersPanel = uipanel(app.InputPanel);
            app.ScanparametersPanel.Title = 'Scan parameters';
            app.XUVloweVEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.XUVloweVEditFieldLabel.Text = 'XUV low (eV)';
            app.XUVloweVEditField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.XUVloweVEditField.ValueChangedFcn = createCallbackFcn(app, @XUVloweVEditFieldValueChanged, true);
            app.XUVloweVEditField.Value = 15;
            app.XUVhigheVEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.XUVhigheVEditFieldLabel.Text = 'XUV high (eV)';
            app.XUVhigheVEditField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.XUVhigheVEditField.ValueChangedFcn = createCallbackFcn(app, @XUVhigheVEditFieldValueChanged, true);
            app.XUVhigheVEditField.Value = 50;
            app.XUVdeltaeVEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.XUVdeltaeVEditFieldLabel.Text = 'XUV delta (eV)';
            app.XUVdeltaeVEditField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.XUVdeltaeVEditField.ValueChangedFcn = createCallbackFcn(app, @XUVdeltaeVEditFieldValueChanged, true);
            app.XUVdeltaeVEditField.Value = 0.1;
            app.DelayrangefsEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.DelayrangefsEditFieldLabel.Text = 'Delay range (fs)';
            app.DelayrangefsEditField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.DelayrangefsEditField.ValueChangedFcn = createCallbackFcn(app, @DelayrangefsEditFieldValueChanged, true);
            app.DelayrangefsEditField.Value = 10;
            app.DelaystepfsEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.DelaystepfsEditFieldLabel.Text = 'Delay step (fs)';
            app.DelaystepfsEditField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.DelaystepfsEditField.ValueChangedFcn = createCallbackFcn(app, @DelaystepfsEditFieldValueChanged, true);
            app.DelaystepfsEditField.Value = 0.01;
            app.NumericalparametersPanel = uipanel(app.InputPanel);
            app.NumericalparametersPanel.Title = 'Numerical parameters';
            app.mmaxEditFieldLabel = uilabel(app.NumericalparametersPanel);
            app.mmaxEditFieldLabel.Text = 'm max';
            app.mmaxEditField = uieditfield(app.NumericalparametersPanel, 'numeric');
            app.mmaxEditField.ValueChangedFcn = createCallbackFcn(app, @mmaxEditFieldValueChanged, true);
            app.mmaxEditField.Value = 1;
            app.lmaxEditFieldLabel = uilabel(app.NumericalparametersPanel);
            app.lmaxEditFieldLabel.Text = 'l max';
            app.lmaxEditField = uieditfield(app.NumericalparametersPanel, 'numeric');
            app.lmaxEditField.Value = 100;
            app.EmaxinteVEditFieldLabel = uilabel(app.NumericalparametersPanel);
            app.EmaxinteVEditFieldLabel.Text = 'E max int (eV)';
            app.EmaxinteVEditField = uieditfield(app.NumericalparametersPanel, 'numeric');
            app.EmaxinteVEditField.ValueChangedFcn = createCallbackFcn(app, @EmaxinteVEditFieldValueChanged2, true);
            app.EmaxinteVEditField.Value = 100;
            app.maxBesselorderEditFieldLabel = uilabel(app.NumericalparametersPanel);
            app.maxBesselorderEditFieldLabel.Text = 'max Bessel order';
            app.maxBesselorderEditField = uieditfield(app.NumericalparametersPanel, 'numeric');
            app.maxBesselorderEditField.ValueChangedFcn = createCallbackFcn(app, @maxBesselorderEditFieldValueChanged2, true);
            app.maxBesselorderEditField.Value = 100;
            app.dEintEditFieldLabel = uilabel(app.NumericalparametersPanel);
            app.dEintEditFieldLabel.Text = 'dEint';
            app.dEintEditField = uieditfield(app.NumericalparametersPanel, 'numeric');
            app.dEintEditField.ValueChangedFcn = createCallbackFcn(app, @dEintEditFieldValueChanged2, true);
            app.dEintEditField.Value = 0.01;
            app.ImpliedparametersPanel = uipanel(app.UIFigure);
            app.ImpliedparametersPanel.Title = 'Implied parameters';
            app.LasermatterparametersPanel = uipanel(app.ImpliedparametersPanel);
            app.LasermatterparametersPanel.Title = 'Laser-matter parameters';
            app.UponderomotiveeVEditFieldLabel = uilabel(app.LasermatterparametersPanel);
            app.UponderomotiveeVEditFieldLabel.Text = 'U ponderomotive (eV)';
            app.UponderomotiveeVEditField = uieditfield(app.LasermatterparametersPanel, 'numeric');
            app.gadiabaticEditFieldLabel = uilabel(app.LasermatterparametersPanel);
            app.gadiabaticEditFieldLabel.Text = 'g adiabatic';
            app.gadiabaticEditField = uieditfield(app.LasermatterparametersPanel, 'numeric');
            app.gKeldyshEditFieldLabel = uilabel(app.LasermatterparametersPanel);
            app.gKeldyshEditFieldLabel.Text = 'g Keldysh';
            app.gKeldyshEditField = uieditfield(app.LasermatterparametersPanel, 'numeric');
            app.ElectricfieldVAEditFieldLabel = uilabel(app.LasermatterparametersPanel);
            app.ElectricfieldVAEditFieldLabel.Text = 'Electric field  (V/A)';
            app.ElectricfieldVAEditField = uieditfield(app.LasermatterparametersPanel, 'numeric');
            app.EElectroopticeVEditFieldLabel = uilabel(app.LasermatterparametersPanel);
            app.EElectroopticeVEditFieldLabel.Text = 'E Electro-optic  (eV)';
            app.EElectroopticeVEditField = uieditfield(app.LasermatterparametersPanel, 'numeric');
            app.IntegrationparametersPanel = uipanel(app.ImpliedparametersPanel);
            app.IntegrationparametersPanel.Title = 'Integration parameters';
            app.dth1minEditFieldLabel = uilabel(app.IntegrationparametersPanel);
            app.dth1minEditFieldLabel.Text = 'dth1 min';
            app.dth1minEditField = uieditfield(app.IntegrationparametersPanel, 'numeric');
            app.lmaxEditField_2Label = uilabel(app.IntegrationparametersPanel);
            app.lmaxEditField_2Label.Text = 'l max';
            app.lmaxEditField_2 = uieditfield(app.IntegrationparametersPanel, 'numeric');
            app.EmaxeVEditFieldLabel = uilabel(app.IntegrationparametersPanel);
            app.EmaxeVEditFieldLabel.Text = 'E max (eV)';
            app.EmaxeVEditField = uieditfield(app.IntegrationparametersPanel, 'numeric');
            app.th2EditFieldLabel = uilabel(app.IntegrationparametersPanel);
            app.th2EditFieldLabel.Text = 'th 2';
            app.th2EditField = uieditfield(app.IntegrationparametersPanel, 'numeric');
            app.th1maxEditField_2Label = uilabel(app.IntegrationparametersPanel);
            app.th1maxEditField_2Label.Text = 'th1 max';
            app.th1maxEditField_2 = uieditfield(app.IntegrationparametersPanel, 'numeric');
            app.CalculatefromlookuptablefastButton = uibutton(app.UIFigure, 'push');
            app.CalculatefromlookuptablefastButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatefromlookuptablefastButtonPushed, true);
            app.CalculatefromlookuptablefastButton.Text = 'Calculate from lookup table (fast)';
            app.LookuptablePanel = uipanel(app.UIFigure);
            app.LookuptablePanel.Title = 'Lookup table';
            app.AkproductrangePanel = uipanel(app.LookuptablePanel);
            app.AkproductrangePanel.Title = 'Ak product  range';
            app.th2minEditFieldLabel = uilabel(app.AkproductrangePanel);
            app.th2minEditFieldLabel.Text = 'th2 min';
            app.th2minEditField = uieditfield(app.AkproductrangePanel, 'numeric');
            app.th2minEditField.ValueChangedFcn = createCallbackFcn(app, @th2minEditFieldValueChanged2, true);
            app.th2minEditField.Value = 0.1;
            app.dth2EditFieldLabel = uilabel(app.AkproductrangePanel);
            app.dth2EditFieldLabel.Text = 'dth2';
            app.dth2EditField = uieditfield(app.AkproductrangePanel, 'numeric');
            app.dth2EditField.ValueChangedFcn = createCallbackFcn(app, @dth2EditFieldValueChanged2, true);
            app.dth2EditField.Value = 0.5;
            app.th2maxEditFieldLabel = uilabel(app.AkproductrangePanel);
            app.th2maxEditFieldLabel.Text = 'th2 max';
            app.th2maxEditField = uieditfield(app.AkproductrangePanel, 'numeric');
            app.th2maxEditField.ValueChangedFcn = createCallbackFcn(app, @th2maxEditFieldValueChanged, true);
            app.th2maxEditField.Value = 1;
            app.th1maxEditFieldLabel = uilabel(app.AkproductrangePanel);
            app.th1maxEditFieldLabel.Text = 'th1 max';
            app.th1maxEditField = uieditfield(app.AkproductrangePanel, 'numeric');
            app.th1maxEditField.Value = 10;
            app.dth1EditFieldLabel = uilabel(app.AkproductrangePanel);
            app.dth1EditFieldLabel.Text = 'dth1';
            app.dth1EditField = uieditfield(app.AkproductrangePanel, 'numeric');
            app.dth1EditField.Value = 0.01;
            app.CreatelookuptableButton = uibutton(app.LookuptablePanel, 'push');
            app.CreatelookuptableButton.ButtonPushedFcn = createCallbackFcn(app, @CreatelookuptableButtonPushed, true);
            app.CreatelookuptableButton.Text = 'Create lookup table';
            app.EditField = uieditfield(app.LookuptablePanel, 'text');
            app.SelectbasepathButton = uibutton(app.UIFigure, 'push');
            app.SelectbasepathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbasepathButtonPushed, true);
            app.SelectbasepathButton.Text = 'Select base path';
            app.basepathEditFieldLabel = uilabel(app.UIFigure);
            app.basepathEditFieldLabel.Text = 'base path';
            app.basepathEditField = uieditfield(app.UIFigure, 'text');
            app.basepathEditField.Value = 'C:\Users\Mikhail\Desktop\DFK data\';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 0.1;
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.timeelapsedminEditFieldLabel = uilabel(app.UIFigure);
            app.timeelapsedminEditFieldLabel.Text = 'time elapsed (min)';
            app.timeelapsedminEditField = uieditfield(app.UIFigure, 'numeric');
            app.mSpinnerLabel = uilabel(app.UIFigure);
            app.mSpinnerLabel.Text = 'm';
            app.mSpinner = uispinner(app.UIFigure);
            app.mSpinner.ValueChangingFcn = createCallbackFcn(app, @mSpinnerValueChanging, true);
            app.m1CheckBox = uicheckbox(app.UIFigure);
            app.m1CheckBox.ValueChangedFcn = createCallbackFcn(app, @m1CheckBoxValueChanged, true);
            app.m1CheckBox.Text = 'm = -1';
            app.m1CheckBox.Value = true;
            app.m1CheckBox_2 = uicheckbox(app.UIFigure);
            app.m1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @m1CheckBox_2ValueChanged, true);
            app.m1CheckBox_2.Text = 'm = 1';
            app.m1CheckBox_2.Value = true;
            app.m0CheckBox = uicheckbox(app.UIFigure);
            app.m0CheckBox.ValueChangedFcn = createCallbackFcn(app, @m0CheckBoxValueChanged, true);
            app.m0CheckBox.Text = 'm = 0';
            app.m0CheckBox.Value = true;
            app.higherordersCheckBox = uicheckbox(app.UIFigure);
            app.higherordersCheckBox.ValueChangedFcn = createCallbackFcn(app, @higherordersCheckBoxValueChanged, true);
            app.higherordersCheckBox.Text = 'higher orders';
            app.higherordersCheckBox.Value = true;
            app.SmCheckBox = uicheckbox(app.UIFigure);
            app.SmCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmCheckBoxValueChanged, true);
            app.SmCheckBox.Text = 'Sm';
            app.SmCheckBox.Value = true;
            app.CmCheckBox = uicheckbox(app.UIFigure);
            app.CmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CmCheckBoxValueChanged, true);
            app.CmCheckBox.Text = 'Cm';
            app.CmCheckBox.Value = true;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.colormapButton = uiradiobutton(app.ButtonGroup);
            app.colormapButton.Text = 'color map';
            app.colormapButton.Value = true;
            app.contourmapButton = uiradiobutton(app.ButtonGroup);
            app.contourmapButton.Text = 'contour map';
            app.ExportfiguresButton = uibutton(app.UIFigure, 'push');
            app.ExportfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfiguresButtonPushed, true);
            app.ExportfiguresButton.Text = 'Export figures';
            app.NlinesEditFieldLabel = uilabel(app.UIFigure);
            app.NlinesEditFieldLabel.Text = 'N lines';
            app.NlinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NlinesEditField.ValueChangedFcn = createCallbackFcn(app, @NlinesEditFieldValueChanged, true);
            app.NlinesEditField.Value = 50;
            app.ApproximationsPanel = uipanel(app.UIFigure);
            app.ApproximationsPanel.Title = 'Approximations';
            app.JButtonGroup = uibuttongroup(app.ApproximationsPanel);
            app.JButtonGroup.Title = 'J';
            app.exactButton = uiradiobutton(app.JButtonGroup);
            app.exactButton.Text = 'exact';
            app.exactButton.Value = true;
            app.smallth2Button = uiradiobutton(app.JButtonGroup);
            app.smallth2Button.Text = 'small th2';
            app.smallth1andsmallth2Button = uiradiobutton(app.JButtonGroup);
            app.smallth1andsmallth2Button.Text = 'small th1 and small th2';
            app.TaylorEditFieldLabel = uilabel(app.JButtonGroup);
            app.TaylorEditFieldLabel.Text = 'Taylor ';
            app.TaylorEditField = uieditfield(app.JButtonGroup, 'numeric');
            app.TaylorEditField.Value = 1;
            app.Chi3Button = uiradiobutton(app.JButtonGroup);
            app.Chi3Button.Text = 'Chi3';
            app.AdiabaticButton = uiradiobutton(app.JButtonGroup);
            app.AdiabaticButton.Text = 'Adiabatic';
            app.SmcalculationButtonGroup = uibuttongroup(app.ApproximationsPanel);
            app.SmcalculationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SmcalculationButtonGroupSelectionChanged, true);
            app.SmcalculationButtonGroup.Title = 'Sm calculation';
            app.PrincipalValueButton = uiradiobutton(app.SmcalculationButtonGroup);
            app.PrincipalValueButton.Text = 'Principal Value';
            app.PrincipalValueButton.Value = true;
            app.HilberttransformButton = uiradiobutton(app.SmcalculationButtonGroup);
            app.HilberttransformButton.Text = 'Hilbert transform';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.colormapButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end